classdef graphic_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        PastadetesteDropDownLabel       matlab.ui.control.Label
        PastadetesteDropDown            matlab.ui.control.DropDown
        TipodeRedeDropDownLabel         matlab.ui.control.Label
        TipodeRedeDropDown              matlab.ui.control.DropDown
        NdeCamadasEscondidasDropDownLabel  matlab.ui.control.Label
        NdeCamadasEscondidasDropDown    matlab.ui.control.DropDown
        FunodeAtiviodaCamadadeOutputDropDownLabel  matlab.ui.control.Label
        FunodeAtiviodaCamadadeOutputDropDown  matlab.ui.control.DropDown
        EditField                       matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        FunodeAtiviodaCamadaEscondidaLabel  matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        DropDown_2                      matlab.ui.control.DropDown
        DropDown_3                      matlab.ui.control.DropDown
        FunodeTreinodaRedeDropDownLabel  matlab.ui.control.Label
        FunodeTreinodaRedeDropDown      matlab.ui.control.DropDown
        TreinarRedeButton               matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        GuardarRedeButton               matlab.ui.control.Button
        NomedoFicheiroEditFieldLabel    matlab.ui.control.Label
        NomedoFicheiroEditField         matlab.ui.control.EditField
        NmaxdeepochsEditFieldLabel      matlab.ui.control.Label
        NmaxdeepochsEditField           matlab.ui.control.NumericEditField
        Panel_3                         matlab.ui.container.Panel
        CarregarRedeButton              matlab.ui.control.Button
        NomedoFicheiroEditField_2Label  matlab.ui.control.Label
        NomedoFicheiroEditField_2       matlab.ui.control.EditField
        FunodeDivisoDropDownLabel       matlab.ui.control.Label
        FunodeDivisoDropDown            matlab.ui.control.DropDown
        trainEditFieldLabel             matlab.ui.control.Label
        trainEditField                  matlab.ui.control.NumericEditField
        valEditFieldLabel               matlab.ui.control.Label
        valEditField                    matlab.ui.control.NumericEditField
        testEditFieldLabel              matlab.ui.control.Label
        testEditField                   matlab.ui.control.NumericEditField
        Panel_4                         matlab.ui.container.Panel
        PrecisoTotalTreinoEditFieldLabel  matlab.ui.control.Label
        PrecisoTotalTreinoEditField     matlab.ui.control.EditField
        PrecisoTestesTreinoEditFieldLabel  matlab.ui.control.Label
        PrecisoTestesTreinoEditField    matlab.ui.control.EditField
        EpochsdeTreinoEditFieldLabel    matlab.ui.control.Label
        EpochsdeTreinoEditField         matlab.ui.control.EditField
        Panel_5                         matlab.ui.container.Panel
        SimularButton                   matlab.ui.control.Button
        PrecisoTotaldoTesteEditFieldLabel  matlab.ui.control.Label
        PrecisoTotaldoTesteEditField    matlab.ui.control.EditField
        Panel_6                         matlab.ui.container.Panel
        NomedoFicheiroEditField_3Label  matlab.ui.control.Label
        NomedoFicheiroEditField_3       matlab.ui.control.EditField
        GuardarRedeButton_2             matlab.ui.control.Button
        PastadetesteDropDown_2Label     matlab.ui.control.Label
        PastadetesteDropDown_2          matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EditField_2.Enable = false;
            app.DropDown_3.Enable = false;
            app.DropDown_2.Enable = false;
            app.EditField_3.Enable = false;
            app.trainEditField.Enable = false;
            app.valEditField.Enable = false;
            app.testEditField.Enable = false;
        end

        % Button pushed function: TreinarRedeButton
        function TreinarRedeButtonPushed(app, event)
            % carregar imgs
            [input,target] = obtemImagens(app.PastadetesteDropDown.Value);
            
            if(app.NdeCamadasEscondidasDropDown.Value == '1')
                camadas = [app.EditField.Value];
            elseif(app.NdeCamadasEscondidasDropDown.Value == '2')
                camadas = [app.EditField.Value app.EditField_3.Value];
            else
                camadas = [app.EditField.Value app.EditField_3.Value app.EditField_2.Value];
            end
            
            switch(app.TipodeRedeDropDown.Value)  % escolha do tipo de rede
                case 'Feed Forward Net'
                    net = feedforwardnet(camadas);
                case 'Cascade Forward Net'
                    net = cascadeforwardnet(camadas);
                otherwise
                    return;
            end
            
            net.trainFcn = app.FunodeTreinodaRedeDropDown.Value; % definir função de Treino
            
            switch( app.NdeCamadasEscondidasDropDown.Value)
                case '1'
                    net.layers{1}.transferFcn = app.DropDown.Value;
                    net.layers{2}.transferFcn = app.FunodeAtiviodaCamadadeOutputDropDown.Value;
                case '2'
                    net.layers{1}.transferFcn = app.DropDown.Value;
                    net.layers{2}.transferFcn = app.DropDown_2.Value;
                    net.layers{3}.transferFcn = app.FunodeAtiviodaCamadadeOutputDropDown.Value;
                case '3'
                    net.layers{1}.transferFcn = app.DropDown.Value;
                    net.layers{2}.transferFcn = app.DropDown_2.Value;
                    net.layers{3}.transferFcn = app.DropDown_3.Value;
                    net.layers{4}.transferFcn = app.FunodeAtiviodaCamadadeOutputDropDown.Value;
                otherwise
                    return;
            end
            
            switch(app.FunodeDivisoDropDown.Value)
                case 'sem_funcao'
                    net.divideFcn = '';
                case 'divirand'
                    net.divideFcn = 'dividerand';
                    net.divideParam.trainRatio = app.trainEditField.Value;
                    net.divideParam.valRatio = app.valEditField.Value;
                    net.divideParam.testRatio = app.testEditField.Value;
                case 'divideblock'
                    net.divideFcn = 'divideblock';
                    net.divideParam.trainRatio = app.trainEditField.Value;
                    net.divideParam.valRatio = app.valEditField.Value;
                    net.divideParam.testRatio = app.testEditField.Value;
                otherwise
                    return;
            end


            net.trainParam.epochs = app.NmaxdeepochsEditField.Value; % define numero de epochs
            
            [net,tr] = train(net, input, target);
            
            out = sim(net, input);
            
            r=0;
            for k=1:size(out,2)
                [~, b] = max(out(:,k));
                [~, d] = max(target(:,k));
                if b == d
                    r = r+1;
                end
            end
            accuracy = r/size(out,2)*100;
            
            app.PrecisoTotalTreinoEditField.Value = accuracy+"%";
            app.EpochsdeTreinoEditField.Value = tr.num_epochs+" iterações";
            
            melhorNet = net;
            save("APPnet.mat",'melhorNet');
        end

        % Button pushed function: GuardarRedeButton
        function GuardarRedeButtonPushed(app, event)
            if isfile("APPnet.mat")
                load("APPnet.mat","melhorNet");
                net = melhorNet;
                clear melhorNet;
            end
            melhorNet = net;
            save(app.NomedoFicheiroEditField.Value+".mat",'melhorNet');
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            if isfile("APPnet.mat")
                load("APPnet.mat","melhorNet");
                net = melhorNet;
                clear melhorNet;
            else
                return;
            end
            % [input,target] = carregaImagens(app.PastadeTesteDropDown.Value);
            % carregar imgs
            [input,target] = obtemImagens(app.PastadetesteDropDown_2.Value);
            
            % Tinput = input(:, tr.testInd);
            %Ttarget = target(:, tr.testInd);
            
            
            out = sim(net,input);
            
            plotconfusion(target, out);
            %Calcula e mostra a percentagem de classificacoes corretas no total dos exemplos
            r=0;
            for k=1:size(out,2)               % Para cada classificacao
                [~, b] = max(out(:,k));          %b guarda a linha onde encontrou valor mais alto da saida obtida
                [~, d] = max(target(:,k));  %d guarda a linha onde encontrou valor mais alto da saida desejada
                if b == d                       % se estao na mesma linha, a classificacao foi correta (incrementa 1)
                    r = r+1;
                end
            end
            accuracy = r/size(out,2)*100;
            app.PrecisoTotaldoTesteEditField.Value = accuracy + "%";
        end

        % Button pushed function: GuardarRedeButton_2
        function GuardarRedeButton_2Pushed(app, event)
            if isfile("APPnet.mat")
                load("APPnet.mat","melhorNet");
                net = melhorNet;
                clear melhorNet;
            else
                return;
            end
            melhorNet = net;
            save(app.NomedoFicheiroEditField_3.Value+".mat",'melhorNet');
        end

        % Value changed function: NdeCamadasEscondidasDropDown
        function NdeCamadasEscondidasDropDownValueChanged(app, event)
            value = app.NdeCamadasEscondidasDropDown.Value;
            switch (value)
                case '1'
                    app.DropDown.Enable = true;
                    app.EditField.Enable = true;
                    app.DropDown_2.Enable = false;
                    app.EditField_2.Enable = false;
                    app.DropDown_3.Enable = false;
                    app.EditField_3.Enable = false;
                case '2'
                    app.DropDown.Enable = true;
                    app.EditField.Enable = true;
                    app.DropDown_2.Enable = true;
                    app.EditField_2.Enable = true;
                    app.DropDown_3.Enable = false;
                    app.EditField_3.Enable = false;
                case '3'
                    app.DropDown.Enable = true;
                    app.EditField.Enable = true;
                    app.DropDown_2.Enable = true;
                    app.EditField_2.Enable = true;
                    app.DropDown_3.Enable = true;
                    app.EditField_3.Enable = true;
            end
        end

        % Value changed function: FunodeDivisoDropDown
        function FunodeDivisoDropDownValueChanged(app, event)
            value = app.FunodeDivisoDropDown.Value;
            switch(value)
                case 'sem_funcao'
                    app.trainEditField.Enable = false;
                    app.valEditField.Enable = false;
                    app.testEditField.Enable = false;
                case 'divirand'
                    app.trainEditField.Enable = true;
                    app.valEditField.Enable = true;
                    app.testEditField.Enable = true;
                case 'divideblock'
                    app.trainEditField.Enable = true;
                    app.valEditField.Enable = true;
                    app.testEditField.Enable = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.PastadetesteDropDownLabel = uilabel(app.Panel);
            app.PastadetesteDropDownLabel.Text = 'Pasta de teste';
            app.PastadetesteDropDown = uidropdown(app.Panel);
            app.PastadetesteDropDown.Items = {'start', 'train', 'test', 'start_test_train', 'images_draws'};
            app.PastadetesteDropDown.Value = 'start';
            app.TipodeRedeDropDownLabel = uilabel(app.Panel);
            app.TipodeRedeDropDownLabel.Text = 'Tipo de Rede';
            app.TipodeRedeDropDown = uidropdown(app.Panel);
            app.TipodeRedeDropDown.Items = {'Feed Forward Net', 'Cascade Forward Net'};
            app.TipodeRedeDropDown.Value = 'Feed Forward Net';
            app.NdeCamadasEscondidasDropDownLabel = uilabel(app.Panel);
            app.NdeCamadasEscondidasDropDownLabel.Text = 'Nº de Camadas Escondidas';
            app.NdeCamadasEscondidasDropDown = uidropdown(app.Panel);
            app.NdeCamadasEscondidasDropDown.Items = {'1', '2', '3'};
            app.NdeCamadasEscondidasDropDown.ValueChangedFcn = createCallbackFcn(app, @NdeCamadasEscondidasDropDownValueChanged, true);
            app.NdeCamadasEscondidasDropDown.Value = '1';
            app.FunodeAtiviodaCamadadeOutputDropDownLabel = uilabel(app.Panel);
            app.FunodeAtiviodaCamadadeOutputDropDownLabel.Text = 'Função de Ativição da Camada de Output';
            app.FunodeAtiviodaCamadadeOutputDropDown = uidropdown(app.Panel);
            app.FunodeAtiviodaCamadadeOutputDropDown.Items = {'tansig', 'logsig', 'purelin', 'hardlim'};
            app.FunodeAtiviodaCamadadeOutputDropDown.Value = 'tansig';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.Value = 5;
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.EditField_3.Value = 5;
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.EditField_2.Value = 5;
            app.FunodeAtiviodaCamadaEscondidaLabel = uilabel(app.Panel);
            app.FunodeAtiviodaCamadaEscondidaLabel.Text = 'Função de Ativição da Camada Escondida';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'logsig', 'purelin', 'tansig', 'hardlim'};
            app.DropDown.Value = 'logsig';
            app.DropDown_2 = uidropdown(app.Panel);
            app.DropDown_2.Items = {'logsig', 'purelin', 'tansig', 'hardlim'};
            app.DropDown_2.Value = 'logsig';
            app.DropDown_3 = uidropdown(app.Panel);
            app.DropDown_3.Items = {'logsig', 'purelin', 'tansig', 'hardlim'};
            app.DropDown_3.Value = 'logsig';
            app.FunodeTreinodaRedeDropDownLabel = uilabel(app.Panel);
            app.FunodeTreinodaRedeDropDownLabel.Text = 'Função de Treino da Rede';
            app.FunodeTreinodaRedeDropDown = uidropdown(app.Panel);
            app.FunodeTreinodaRedeDropDown.Items = {'trainlm', 'traingdx', 'traingd', 'trainscg'};
            app.FunodeTreinodaRedeDropDown.Value = 'trainlm';
            app.TreinarRedeButton = uibutton(app.Panel, 'push');
            app.TreinarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @TreinarRedeButtonPushed, true);
            app.TreinarRedeButton.Text = 'Treinar Rede';
            app.Panel_2 = uipanel(app.Panel);
            app.GuardarRedeButton = uibutton(app.Panel_2, 'push');
            app.GuardarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarRedeButtonPushed, true);
            app.GuardarRedeButton.Text = 'Guardar Rede';
            app.NomedoFicheiroEditFieldLabel = uilabel(app.Panel_2);
            app.NomedoFicheiroEditFieldLabel.Text = 'Nome do Ficheiro';
            app.NomedoFicheiroEditField = uieditfield(app.Panel_2, 'text');
            app.NmaxdeepochsEditFieldLabel = uilabel(app.Panel);
            app.NmaxdeepochsEditFieldLabel.Text = 'Nº max de epochs';
            app.NmaxdeepochsEditField = uieditfield(app.Panel, 'numeric');
            app.NmaxdeepochsEditField.Value = 400;
            app.Panel_3 = uipanel(app.Panel);
            app.CarregarRedeButton = uibutton(app.Panel_3, 'push');
            app.CarregarRedeButton.Text = 'Carregar Rede';
            app.NomedoFicheiroEditField_2Label = uilabel(app.Panel_3);
            app.NomedoFicheiroEditField_2Label.Text = 'Nome do Ficheiro';
            app.NomedoFicheiroEditField_2 = uieditfield(app.Panel_3, 'text');
            app.FunodeDivisoDropDownLabel = uilabel(app.Panel);
            app.FunodeDivisoDropDownLabel.Text = 'Função de Divisão';
            app.FunodeDivisoDropDown = uidropdown(app.Panel);
            app.FunodeDivisoDropDown.Items = {'sem_funcao', 'divirand', 'divideblock'};
            app.FunodeDivisoDropDown.ValueChangedFcn = createCallbackFcn(app, @FunodeDivisoDropDownValueChanged, true);
            app.FunodeDivisoDropDown.Value = 'sem_funcao';
            app.trainEditFieldLabel = uilabel(app.Panel);
            app.trainEditFieldLabel.Text = 'train';
            app.trainEditField = uieditfield(app.Panel, 'numeric');
            app.trainEditField.Value = 0.15;
            app.valEditFieldLabel = uilabel(app.Panel);
            app.valEditFieldLabel.Text = 'val';
            app.valEditField = uieditfield(app.Panel, 'numeric');
            app.valEditField.Value = 0.15;
            app.testEditFieldLabel = uilabel(app.Panel);
            app.testEditFieldLabel.Text = 'test';
            app.testEditField = uieditfield(app.Panel, 'numeric');
            app.testEditField.Value = 0.3;
            app.Panel_4 = uipanel(app.UIFigure);
            app.PrecisoTotalTreinoEditFieldLabel = uilabel(app.Panel_4);
            app.PrecisoTotalTreinoEditFieldLabel.Text = 'Precisão Total Treino';
            app.PrecisoTotalTreinoEditField = uieditfield(app.Panel_4, 'text');
            app.PrecisoTestesTreinoEditFieldLabel = uilabel(app.Panel_4);
            app.PrecisoTestesTreinoEditFieldLabel.Text = 'Precisão Testes Treino';
            app.PrecisoTestesTreinoEditField = uieditfield(app.Panel_4, 'text');
            app.EpochsdeTreinoEditFieldLabel = uilabel(app.Panel_4);
            app.EpochsdeTreinoEditFieldLabel.Text = 'Epochs de Treino';
            app.EpochsdeTreinoEditField = uieditfield(app.Panel_4, 'text');
            app.Panel_5 = uipanel(app.UIFigure);
            app.SimularButton = uibutton(app.Panel_5, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.PrecisoTotaldoTesteEditFieldLabel = uilabel(app.Panel_5);
            app.PrecisoTotaldoTesteEditFieldLabel.Text = 'Precisão Total do Teste';
            app.PrecisoTotaldoTesteEditField = uieditfield(app.Panel_5, 'text');
            app.Panel_6 = uipanel(app.Panel_5);
            app.NomedoFicheiroEditField_3Label = uilabel(app.Panel_6);
            app.NomedoFicheiroEditField_3Label.Text = 'Nome do Ficheiro';
            app.NomedoFicheiroEditField_3 = uieditfield(app.Panel_6, 'text');
            app.GuardarRedeButton_2 = uibutton(app.Panel_6, 'push');
            app.GuardarRedeButton_2.ButtonPushedFcn = createCallbackFcn(app, @GuardarRedeButton_2Pushed, true);
            app.GuardarRedeButton_2.Text = 'Guardar Rede';
            app.PastadetesteDropDown_2Label = uilabel(app.Panel_5);
            app.PastadetesteDropDown_2Label.Text = 'Pasta de teste';
            app.PastadetesteDropDown_2 = uidropdown(app.Panel_5);
            app.PastadetesteDropDown_2.Items = {'start', 'train', 'test', 'start_test_train', 'images_draws'};
            app.PastadetesteDropDown_2.Value = 'start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graphic_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end